classdef replaceBlockNamesAndVals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FindandReplaceUIFigure        matlab.ui.Figure
        ReplaceoptionDropDown         matlab.ui.control.DropDown
        ReplaceoptionDropDown_2Label  matlab.ui.control.Label
        ReplacetypeDropDown           matlab.ui.control.DropDown
        ReplacetypeDropDownLabel      matlab.ui.control.Label
        RegexCheckBox                 matlab.ui.control.CheckBox
        ReplaceEditField              matlab.ui.control.EditField
        ReplaceEditFieldLabel         matlab.ui.control.Label
        FindEditField                 matlab.ui.control.EditField
        FindEditFieldLabel            matlab.ui.control.Label
        ReplaceButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReplaceButton
        function replaceBtnCallback(app, event)
            blks = [];
            replaceType = 'Name';
            regexSwitch = app.RegexCheckBox.Value;
            findStr = app.FindEditField.Value;
            replaceStr = app.ReplaceEditField.Value;

            if ~isempty(findStr) && ~isempty(replaceStr)

                % Parse Replace Option
                replaceOpt = app.ReplaceoptionDropDown.Value;
                
                if strcmp(replaceOpt,'Selected')
                    blks = getSelectedBlockHandles();
                elseif strcmp(replaceOpt,'Current Layer')
                    tmp = find_system(gcs,'SearchDepth',1);
                    blks = cell2mat(get_param({tmp{2:end}},'handle'));
                elseif strcmp(replaceOpt,'All Layers')
                    tmp = find_system(bdroot);
                    blks = cell2mat(get_param({tmp{2:end}},'handle'));
                end
                % Parse Replace type
                replaceTypeVal = app.ReplacetypeDropDown.Value;
    
                if strcmp(replaceTypeVal,'Name')
                    replaceType = 'Name';
                elseif strcmp(replaceTypeVal,'Value')
                    replaceType = 'Value';
                end
    
                % Replace name or value
    
                for ind = 1:numel(blks)
                    if isfield(get_param(blks(ind),'ObjectParameters'),replaceType)
                        strVal = get_param(blks(ind),replaceType);
                        if regexSwitch
                            newStrVal = regexprep(strVal,findStr,replaceStr);
                        else
                            newStrVal = replace(strVal,findStr,replaceStr);
                        end
                        set_param(blks(ind),replaceType,newStrVal);
                    end
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FindandReplaceUIFigure = uifigure('Visible', 'off');
            app.FindandReplaceUIFigure.Name = 'Find and Replace';
            app.ReplaceButton = uibutton(app.FindandReplaceUIFigure, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @replaceBtnCallback, true);
            app.ReplaceButton.Text = 'Replace';
            app.FindEditFieldLabel = uilabel(app.FindandReplaceUIFigure);
            app.FindEditFieldLabel.Text = 'Find';
            app.FindEditField = uieditfield(app.FindandReplaceUIFigure, 'text');
            app.ReplaceEditFieldLabel = uilabel(app.FindandReplaceUIFigure);
            app.ReplaceEditFieldLabel.Text = 'Replace';
            app.ReplaceEditField = uieditfield(app.FindandReplaceUIFigure, 'text');
            app.RegexCheckBox = uicheckbox(app.FindandReplaceUIFigure);
            app.RegexCheckBox.Text = 'Regex';
            app.ReplacetypeDropDownLabel = uilabel(app.FindandReplaceUIFigure);
            app.ReplacetypeDropDownLabel.Text = 'Replace type';
            app.ReplacetypeDropDown = uidropdown(app.FindandReplaceUIFigure);
            app.ReplacetypeDropDown.Items = {'Name', 'Value'};
            app.ReplacetypeDropDown.Value = 'Name';
            app.ReplaceoptionDropDown_2Label = uilabel(app.FindandReplaceUIFigure);
            app.ReplaceoptionDropDown_2Label.Text = 'Replace option';
            app.ReplaceoptionDropDown = uidropdown(app.FindandReplaceUIFigure);
            app.ReplaceoptionDropDown.Items = {'Selected', 'Current Layer', 'All Layers'};
            app.ReplaceoptionDropDown.Value = 'Selected';
            % Show the figure after all components are created
            app.FindandReplaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = replaceBlockNamesAndVals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FindandReplaceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FindandReplaceUIFigure)
        end
    end
end